classdef figrApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FIGRParameterExplorerUIFigure  matlab.ui.Figure
        xntgAxes                       matlab.ui.control.UIAxes
        pSmoothingLabel                matlab.ui.control.Label
        pSmoothingSlider               matlab.ui.control.Slider
        GeneListBoxLabel               matlab.ui.control.Label
        GeneListBox                    matlab.ui.control.ListBox
        NucleusListBoxLabel            matlab.ui.control.Label
        NucleusListBox                 matlab.ui.control.ListBox
        xThresholdSliderLabel          matlab.ui.control.Label
        xThresholdSlider               matlab.ui.control.Slider
        SaveoptionsButton              matlab.ui.control.Button
        LoadoptionsButton              matlab.ui.control.Button
        tSafetySliderLabel             matlab.ui.control.Label
        tSafetySlider                  matlab.ui.control.Slider
        vThresholdQuestionButton       matlab.ui.control.Button
        xThresholdQuestionButton       matlab.ui.control.Button
        pSmoothingQuestionButton       matlab.ui.control.Button
        tSafetyQuestionButton          matlab.ui.control.Button
        vThresholdSliderLabel          matlab.ui.control.Label
        vThresholdSlider               matlab.ui.control.Slider
        LoadexpressiondataMDAButton    matlab.ui.control.Button
        SaveexpressiondataButton       matlab.ui.control.Button
        LoadtimepointsMDAButton        matlab.ui.control.Button
        InferGRNButton                 matlab.ui.control.Button
    end

    
    properties (Access = private)
        xntg % Description
        tt
        nucleusNames
        geneNames
        pvxArr
        pvxUser
        paramFileLoaded = 0; % Flag for loaded file with p, v, and x parameters
        userChangedParam % Flag for wheather user has changed the params on the gui
        geneMap = containers.Map
        nucleusMap = containers.Map
    end
    
    methods (Access = private)
        
        %==========================================================================================
        %  DRAW THE GENE EXPRESSION TRAJECTORY FOR THE SELECTED GENE IN THE SELECTED NUCLEUS (OR CONDITION)
        %==========================================================================================
        function redrawXNTGAxes (app)
            tt = app.tt;
            
            gNames = string(app.GeneListBox.Value);                      % name of selected gene
            gNames = gNames(:);
            [commonElements,gIndices] = intersect(app.geneNames, gNames,'stable');
            nNames = string(app.NucleusListBox.Value);            % name of selected gene (made to string)
            nNames = nNames(:);                            % flatten (otherwise it's column vec)
            nIndices = find (app.nucleusNames==nNames);     % index of selected gene
            
            % plot trajs
            cla (app.xntgAxes);
            hold (app.xntgAxes, 'on');
            tpltmin = min(tt);
            tpltmax = max(tt);
            tplt = linspace (tpltmin, tpltmax, 50);
            tplt = unique ([tplt'  ; tt]);
           
            colorOrder = get (app.xntgAxes, 'colororder');
            vt = [];
            
            p = app.pSmoothingSlider.Value;                                
            vThreshold = app.vThresholdSlider.Value;
            xThreshold = app.xThresholdSlider.Value;
            tsafety = app.tSafetySlider.Value;
            
            %-----------------------------------------------
            % Visualize the thresholds
            %-----------------------------------------------
            plot (app.xntgAxes, ...
                [tpltmin tpltmax], [xThreshold xThreshold], 'k--', ...
                [tpltmin tpltmax], [xThreshold xThreshold+vThreshold*(tpltmax-tpltmin)], 'k--', ...
                [tpltmin tpltmax], [xThreshold xThreshold-vThreshold*(tpltmax-tpltmin)], 'k--');
                               
            %-----------------------------------------------
            % Loop over all selected genes and nuclei
            %-----------------------------------------------
            for k=1:length(gIndices) 
                for j=1:length(nIndices)
                    n = nIndices(j);
                    g = gIndices(k);
                    xSplineFN = csaps (tt, app.xntg(n,:,g), p);
                    xSpline = fnval (xSplineFN,tplt);
                    vSplineFN = fnder (xSplineFN);         % Calculate derivative w.r.t. gene g!
                    for t = 1:length(tt)                   % At each timepoint
                        vt(t) = fnval (vSplineFN, tt(t));  % Evaluate derivative
                        if (abs(vt(t)) > vThreshold)	 % If derivative is significant
                            yt(t) = sign (vt(t));      % Infer on/off state from sign of derivative
                        else							% Else infer on/off state from size of x itself
                            yt(t) = sign (app.xntg(n,t,g) - xThreshold);
                        end           					% YLL 2018-10-2
                    end   
                    
                    %======= Check tsafety.                      
                    tmax = length(tt);
                    ytFilt = yt;                   
                    for t=1+tsafety:tmax-tsafety
                        % FILTER OUT DATAPOINTS WHOSE SLOPES ARE
                        % NOT PART OF A RUN OF tSafety OR MORE OF THE SAME
                        for t2=t-tsafety : t+tsafety
                            if (yt(t2) ~= yt(t))
                                ytFilt(t) = 0.5;
                            end
                        end
                    end % for t
                    
                    
                    yt = ytFilt;
                    %======= Plot ON points as filled circles, OFF points as empty
                    %circles, DROPPED points as crosses
                    tSel = (yt>=1);
                    plot (app.xntgAxes, app.tt(tSel), app.xntg (n,tSel,g), 'o', 'MarkerFaceColor', colorOrder(j,:), 'color', colorOrder(j,:));
                    tSel = (yt<=0);
                    plot (app.xntgAxes, app.tt(tSel), app.xntg (n,tSel,g), 'o', 'color', colorOrder(j,:));
                    tSel = (yt==0.5);
                    plot (app.xntgAxes, app.tt(tSel), app.xntg (n,tSel,g), 'x', 'color', colorOrder(j,:));
                    %======= Plot spline
                    plot (app.xntgAxes, tplt, xSpline, '-', 'color', colorOrder(j,:));
                end
            end
            %app.xntgAxes.YLim = [0 1];    % fix this
            app.xntgAxes.YLim = [0   max(app.xntg(:))];    %YLL 2020-6-1: resize vertical axis to fit all x(n,t,g) data
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            
            %==========================================================================================
            %  READ THE DATA FILE!
            %==========================================================================================
            [app.xntg, app.tt, app.nucleusNames, app.geneNames] = readGeneExprFiles (); % see readGeneExprFiles.m
            
            %==========================================================================================
            %  POPULATE THE ListBox and DropDown WITH ITEMS FOR THE USER TO
            %  SELECT
            %==========================================================================================
            app.GeneListBox.Items = app.geneNames;
            app.NucleusListBox.Items = app.nucleusNames;
            
            app.pSmoothingSlider.MajorTicks = linspace (0, 1, 11);
            app.vThresholdSlider.MajorTicks = linspace (0, 0.02, 11);
            app.xThresholdSlider.MajorTicks = linspace (0, 1, 11);
            
            [numNuclei row] = size(app.nucleusNames);
            [numGenes row] = size(app.geneNames);

            % Store values of params p, v, and x for each gene and nucleus [DEFAULT UNIVERSAL VALUES].
            app.pvxArr(1:numGenes, 1:numNuclei, 1) = app.pSmoothingSlider.Value;
            app.pvxArr(1:numGenes, 1:numNuclei, 2) = app.vThresholdSlider.Value;
            app.pvxArr(1:numGenes, 1:numNuclei, 3) = app.xThresholdSlider.Value;
            
            % Mapper for gene - number (in array)
            app.geneMap = containers.Map(app.geneNames, [1:numGenes]);           
            % Mapper for nucleus - number (in array)
            app.nucleusMap = containers.Map(app.nucleusNames, [1:numNuclei]);
                      
            redrawXNTGAxes (app);
        end

        % Value changed function: GeneListBox
        function GeneListBoxValueChanged(app, event)
            
            value = app.GeneListBox.Value;
            %g = app.geneMap(string(value));  
            gList = values (app.geneMap, value); % YLL 2020-6-1
            gList = [gList{:}];     % convert strings to integers (gene indices)
            if (numel(gList)==1)
                
                %======== User has selected EXACTLY one gene.  Run Joanna's
                %code.
                g = gList;
            
                % Update inserted by the user parameters
                for i=1:numel(string(app.NucleusListBox.Value))
                    app.pSmoothingSlider.Value = app.pvxArr(app.geneMap(string(app.GeneListBox.Value)), ...
                        app.nucleusMap(string(app.NucleusListBox.Value(i))), 1);
                    app.vThresholdSlider.Value = app.pvxArr(app.geneMap(string(app.GeneListBox.Value)), ...
                        app.nucleusMap(string(app.NucleusListBox.Value(i))), 2);
                    app.xThresholdSlider.Value = app.pvxArr(app.geneMap(string(app.GeneListBox.Value)), ...
                        app.nucleusMap(string(app.NucleusListBox.Value(i))), 3);
                end
                
                if(app.paramFileLoaded)
                    for n=1:numel(string(app.NucleusListBox.Value))
                        if(app.userChangedParam(1,n,g) == 0)
                            app.pSmoothingSlider.Value = app.pvxUser(1,n,g);
                        end
                        if(app.userChangedParam(2,n,g) == 0)
                            app.vThresholdSlider.Value = app.pvxUser(2,n,g);
                        end
                        if(app.userChangedParam(3,n,g) == 0)
                            app.xThresholdSlider.Value = app.pvxUser(3,n,g);
                        end
                    end
                end
                
            end
 
            fprintf ("APP: Selected gene(s) %s\n", string(value));           
            redrawXNTGAxes (app);

        end

        % Value changed function: NucleusListBox
        function NucleusListBoxValueChanged(app, event)
            value = app.NucleusListBox.Value;
            nList = values (app.nucleusMap, value); % YLL 2020-6-1
            nList = [nList{:}];     % convert nucleus-name strings to integers (gene indices)
            
            % Update inserted by the user parameters
            
            %  YLL 2020-6-1:  Joanna, I think this is not correct.  I am
            %  getting errors.  
            for i=1:numel(string(app.NucleusListBox.Value))
                app.pSmoothingSlider.Value = app.pvxArr(app.geneMap(string(app.GeneListBox.Value)), ...
                    app.nucleusMap(string(app.NucleusListBox.Value(i))), 1);
                 app.vThresholdSlider.Value = app.pvxArr(app.geneMap(string(app.GeneListBox.Value)), ...
                    app.nucleusMap(string(app.NucleusListBox.Value(i))), 2);
                 app.xThresholdSlider.Value = app.pvxArr(app.geneMap(string(app.GeneListBox.Value)), ...
                    app.nucleusMap(string(app.NucleusListBox.Value(i))), 3);
            end
            
            fprintf ("APP: Selected nucle[i] %s\n", string(value));
            redrawXNTGAxes (app);
        end

        % Value changed function: pSmoothingSlider
        function pSmoothingSliderValueChanged(app, event)
            value = app.pSmoothingSlider.Value;
            % Store changed p value to pvxArr array.
            for i=1:numel(string(app.NucleusListBox.Value))
                app.pvxArr(app.geneMap(string(app.GeneListBox.Value)), ...
                    app.nucleusMap(string(app.NucleusListBox.Value(i))), 1) = value;
            end
            % Set flag to 1 for changed param after file with parameter was loaded.
            if(app.paramFileLoaded)                          
                g = app.geneMap(string(app.GeneListBox.Value));             
                for i=1:numel(string(app.NucleusListBox.Value))
                    n = app.nucleusMap(string(app.NucleusListBox.Value(i)));
                    app.userChangedParam(1,n,g) = 1;
                end              
            end
            redrawXNTGAxes (app);
        end

        % Value changed function: vThresholdSlider
        function vThresholdSliderValueChanged(app, event)
            value = app.vThresholdSlider.Value;
            % Store changed v value to pvxArr array.
            for i=1:numel(string(app.NucleusListBox.Value))
                app.pvxArr(app.geneMap(string(app.GeneListBox.Value)), ...
                    app.nucleusMap(string(app.NucleusListBox.Value(i))), 2) = value;
            end
            % Set flag to 1 for changed param after file with parameter was loaded.
            if(app.paramFileLoaded)                          
                g = app.geneMap(string(app.GeneListBox.Value));             
                for i=1:numel(string(app.NucleusListBox.Value))
                    n = app.nucleusMap(string(app.NucleusListBox.Value(i)));
                    app.userChangedParam(2,n,g) = 1;
                end              
            end
            redrawXNTGAxes (app);
        end

        % Value changed function: xThresholdSlider
        function xThresholdSliderValueChanged(app, event)
            value = app.xThresholdSlider.Value;
            % Store changed x value to pvxArr array.
            for i=1:numel(string(app.NucleusListBox.Value))
                app.pvxArr(app.geneMap(string(app.GeneListBox.Value)), app.nucleusMap(string(app.NucleusListBox.Value(i))), 3) = value;
            end
            
            % Set flag to 1 for changed param after file with parameter was loaded.
            if(app.paramFileLoaded)                          
                g = app.geneMap(string(app.GeneListBox.Value));             
                for i=1:numel(string(app.NucleusListBox.Value))
                    n = app.nucleusMap(string(app.NucleusListBox.Value(i)));
                    app.userChangedParam(3,n,g) = 1;
                end              
            end
            
            redrawXNTGAxes (app);
        end

        % Button pushed function: SaveoptionsButton
        function SaveoptionsButtonPushed(app, event)
            
            [file, path] = uiputfile('*'); %open file
            
            filename = strcat(path,file);
            fmtstr = '%.4f';
            
            [numNuclei row] = size(app.nucleusNames);
            [numGenes row] = size(app.geneNames);
            
            fid = fopen (filename, 'w');

           fprintf (fid, '3 dims\n'); % 3D 
           fprintf (fid, '3 elems\n'); % 3 elements p, v, and a.
           fprintf (fid, '%d', numNuclei);
           fprintf (fid, ' elems\n');          
           fprintf (fid, '%d', numGenes);
           fprintf (fid, ' elems\n');
           
           for i=1:numGenes
                for j=1:numNuclei

                    %fprintf('Gene: %s Nuclei: %s| p=%f v=%f x=%f \n',string(geneName), ...
                    %string(nucleusName), app.pvxArr(i,j,1), app.pvxArr(i,j,2));
                
                    fprintf (fid, fmtstr, string(app.pvxArr(i,j,1)));
                    fprintf (fid, '\t');
                    fprintf (fid, fmtstr, string(app.pvxArr(i,j,2)));
                    fprintf (fid, '\t');
                    fprintf (fid, fmtstr, string(app.pvxArr(i,j,3)));
                    fprintf (fid, '\n');
                end
                
                fprintf (fid, '\n');
                
            end
            
            fclose (fid);
                     
        end

        % Button pushed function: LoadoptionsButton
        function LoadoptionsButtonPushed(app, event)
            
            [file, path] = uigetfile('*'); % Open file.
            filename = strcat(path,file);
            
            % Read input file with p, v, and x paramaters.
            fid = fopen (filename, 'r');
                dmax = fscanf (fid, '%d');   % Read number of dimensions.
            fgets (fid);                 % Skip to next line.
            for d=1:dmax
                cmaxd(d) = fscanf (fid, '%d');  % Read number of elements.
                fgets (fid);                    % Skip to next line.
            end
            app.pvxUser = fscanf (fid, '%f');      % Read in all elements.
            app.pvxUser = reshape (app.pvxUser, cmaxd);
            fclose (fid);
            
            app.paramFileLoaded = 1;
            
            % Initialize array for checking whether a user had changed 
            % the parameters p, v, and x after loading the params file.
            
            [numNuclei row] = size(app.nucleusNames);
            [numGenes row] = size(app.geneNames);           
            for n=1:numNuclei
                for g=1:numGenes
                    app.userChangedParam(1,n,g) = 0;
                    app.userChangedParam(2,n,g) = 0;
                    app.userChangedParam(3,n,g) = 0;
                end
            end
             
            for n=1:numNuclei
                for g=1:numGenes
                    app.pvxArr(g,n,1) = app.pvxUser(1,n,g);
                    app.pvxArr(g,n,2) = app.pvxUser(2,n,g);
                    app.pvxArr(g,n,3) = app.pvxUser(3,n,g);
                    % Update current slider values.
                    app.pSmoothingSlider.Value = app.pvxUser(1,n,g);
                    app.vThresholdSlider.Value = app.pvxUser(2,n,g);
                    app.xThresholdSlider.Value = app.pvxUser(3,n,g);
                end
            end
            
            redrawXNTGAxes (app);
            
        end

        % Value changed function: tSafetySlider
        function tSafetySliderValueChanged(app, event)
            value = app.tSafetySlider.Value;
            % Force cursor to land on discrete values.
            % Determine which discrete option the current value is closest to.
            [~, minIdx] = min(abs(value - event.Source.MajorTicks(:)));
            % Move the slider to that option.
            event.Source.Value = event.Source.MajorTicks(minIdx);            
            value = event.Source.MajorTicks(minIdx);
            redrawXNTGAxes (app);
        end

        % Button pushed function: vThresholdQuestionButton
        function vThresholdQuestionButtonPushed(app, event)
            msgbox({'vThreshold controls the derivative threshold for the ON/OFF state of the gene at time t.'});      
        end

        % Button pushed function: xThresholdQuestionButton
        function xThresholdQuestionButtonPushed(app, event)
            msgbox({'xThreshold controls the expression threshold for the ON/OFF state of the gene if its derivative at time t is greater than vThreshold.'});
        end

        % Button pushed function: pSmoothingQuestionButton
        function pSmoothingQuestionButtonPushed(app, event)
            msgbox({'pSmoothing controls the smoothing parameter of the cubic spline.' });
        end

        % Button pushed function: tSafetyQuestionButton
        function tSafetyQuestionButtonPushed(app, event)
            msgbox({'tSafety param filters out data points when estimating params R and lambda. Filtered data points have slopes are not part of a run of its value. This param is not stored in output but provides '});
        end

        % Button pushed function: LoadexpressiondataMDAButton
        function LoadexpressiondataMDAButtonPushed(app, event)
            
            [file, path] = uigetfile('*');   % YLL 2020-6-1.  Launch "Open File" dialog.
            filename = strcat (path,file);  %"fly_xntg.mda.txt";

            %==========================================================================================
            %  READ THE EXPRESSION DATA FILE!
            %==========================================================================================
            app.xntg = readArray (filename);
            [nmax tmax gmax] = size (app.xntg);
            app.geneNames = string(cellstr(transpose(char( 64+(1:gmax) ))));     % default gene names (letters)
            app.nucleusNames = string(1 : nmax)';  % default nucleus names (integers)
            app.tt = (1 : tmax)';                   % default timepoints (integers)
            
            %==========================================================================================
            %  POPULATE THE ListBox and DropDown WITH ITEMS FOR THE USER TO
            %  SELECT
            %==========================================================================================
            app.GeneListBox.Items = app.geneNames;
            app.NucleusListBox.Items = app.nucleusNames;
            
            app.pSmoothingSlider.MajorTicks = linspace (0, 1, 11);
            app.vThresholdSlider.MajorTicks = linspace (0, 0.02, 11);
            app.xThresholdSlider.MajorTicks = linspace (0, 1, 11);
            
            [numNuclei row] = size(app.nucleusNames);
            [numGenes row] = size(app.geneNames);

            % Store values of params p, v, and x for each gene and nucleus.
            app.pvxArr(1:numGenes, 1:numNuclei, 1) = app.pSmoothingSlider.Value;
            app.pvxArr(1:numGenes, 1:numNuclei, 2) = app.vThresholdSlider.Value;
            app.pvxArr(1:numGenes, 1:numNuclei, 3) = app.xThresholdSlider.Value;
            
            % Mapper for gene - number (in array)
            app.geneMap = containers.Map(app.geneNames, [1:numGenes]);           
            % Mapper for nucleus - number (in array)
            app.nucleusMap = containers.Map(app.nucleusNames, [1:numNuclei]);
            
            redrawXNTGAxes (app);   
        end

        % Button pushed function: LoadtimepointsMDAButton
        function LoadtimepointsMDAButtonPushed(app, event)
            
            [file, path] = uigetfile('*');   % YLL 2020-6-1.  Launch "Open File" dialog.
            filename = strcat (path,file);    %"fly_tn.mda.txt";
            
            %==========================================================================================
            %  READ THE TIMEPOINTS FILE!
            %==========================================================================================
            app.tt = readArray (filename);
            tmax = numel (app.tt);
            redrawXNTGAxes (app);         
        end

        % Button pushed function: InferGRNButton
        function InferGRNButtonPushed(app, event)
            
            
            % Ack!  This is NOT right.... Need to do on per-gene basis!!!!
            %
            opts = struct(  'debug', 0, ...                 
                'slopethresh', 1.0, ...         
                'exprthresh', 0.5, ...
                'splinesmoothing', 0.01, ...
                'spatialsmoothing', 0.5, ...
                'minborder_expr_ratio', 0.01, ...
                'Rld_method', 'slope', ...
                'Rld_tsafety', 3, ...
                'synthesisfunction', 'synthesis_sigmoid_sqrt', ...
                'ODEAbsTol', 1e-3, ...
                'ODEsolver', 'ode45', ...
                'pvxParams', pvxParams, ...
                'lambda', 0.5, ...
                'lm', 'FIGRlogReg');             
            
            [grn, diagnostics] = infer (opts, app.xntg, app.tt, numGenes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FIGRParameterExplorerUIFigure = uifigure('Visible', 'off');
            app.FIGRParameterExplorerUIFigure.Name = 'FIGR Parameter Explorer';
            app.xntgAxes = uiaxes(app.FIGRParameterExplorerUIFigure);
            title(app.xntgAxes, 'Gene expression trajectory')
            xlabel(app.xntgAxes, 'Time t')
            ylabel(app.xntgAxes, 'Expression x')
            app.pSmoothingLabel = uilabel(app.FIGRParameterExplorerUIFigure);
            app.pSmoothingLabel.Text = 'pSmoothing';
            app.pSmoothingSlider = uislider(app.FIGRParameterExplorerUIFigure);
            app.pSmoothingSlider.Limits = [0 1];
            app.pSmoothingSlider.ValueChangedFcn = createCallbackFcn(app, @pSmoothingSliderValueChanged, true);
            app.pSmoothingSlider.Value = 0.7;
            app.GeneListBoxLabel = uilabel(app.FIGRParameterExplorerUIFigure);
            app.GeneListBoxLabel.Text = 'Gene';
            app.GeneListBox = uilistbox(app.FIGRParameterExplorerUIFigure);
            app.GeneListBox.Multiselect = 'on';
            app.GeneListBox.ValueChangedFcn = createCallbackFcn(app, @GeneListBoxValueChanged, true);
            app.GeneListBox.Value = {'Item 1'};
            app.NucleusListBoxLabel = uilabel(app.FIGRParameterExplorerUIFigure);
            app.NucleusListBoxLabel.Text = 'Nucleus';
            app.NucleusListBox = uilistbox(app.FIGRParameterExplorerUIFigure);
            app.NucleusListBox.Items = {'8226', '8227'};
            app.NucleusListBox.Multiselect = 'on';
            app.NucleusListBox.ValueChangedFcn = createCallbackFcn(app, @NucleusListBoxValueChanged, true);
            app.NucleusListBox.Value = {'8226', '8227'};
            app.xThresholdSliderLabel = uilabel(app.FIGRParameterExplorerUIFigure);
            app.xThresholdSliderLabel.Text = 'xThreshold';
            app.xThresholdSlider = uislider(app.FIGRParameterExplorerUIFigure);
            app.xThresholdSlider.Limits = [0 1];
            app.xThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @xThresholdSliderValueChanged, true);
            app.xThresholdSlider.Value = 0.5;
            app.SaveoptionsButton = uibutton(app.FIGRParameterExplorerUIFigure, 'push');
            app.SaveoptionsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveoptionsButtonPushed, true);
            app.SaveoptionsButton.Text = 'Save options';
            app.LoadoptionsButton = uibutton(app.FIGRParameterExplorerUIFigure, 'push');
            app.LoadoptionsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadoptionsButtonPushed, true);
            app.LoadoptionsButton.Text = 'Load options';
            app.tSafetySliderLabel = uilabel(app.FIGRParameterExplorerUIFigure);
            app.tSafetySliderLabel.Text = 'tSafety';
            app.tSafetySlider = uislider(app.FIGRParameterExplorerUIFigure);
            app.tSafetySlider.Limits = [0 10];
            app.tSafetySlider.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.tSafetySlider.ValueChangedFcn = createCallbackFcn(app, @tSafetySliderValueChanged, true);
            app.tSafetySlider.MinorTicks = [];
            app.vThresholdQuestionButton = uibutton(app.FIGRParameterExplorerUIFigure, 'push');
            app.vThresholdQuestionButton.ButtonPushedFcn = createCallbackFcn(app, @vThresholdQuestionButtonPushed, true);
            app.vThresholdQuestionButton.Text = '?';
            app.xThresholdQuestionButton = uibutton(app.FIGRParameterExplorerUIFigure, 'push');
            app.xThresholdQuestionButton.ButtonPushedFcn = createCallbackFcn(app, @xThresholdQuestionButtonPushed, true);
            app.xThresholdQuestionButton.Text = '?';
            app.pSmoothingQuestionButton = uibutton(app.FIGRParameterExplorerUIFigure, 'push');
            app.pSmoothingQuestionButton.ButtonPushedFcn = createCallbackFcn(app, @pSmoothingQuestionButtonPushed, true);
            app.pSmoothingQuestionButton.Text = '?';
            app.tSafetyQuestionButton = uibutton(app.FIGRParameterExplorerUIFigure, 'push');
            app.tSafetyQuestionButton.ButtonPushedFcn = createCallbackFcn(app, @tSafetyQuestionButtonPushed, true);
            app.tSafetyQuestionButton.Text = '?';
            app.vThresholdSliderLabel = uilabel(app.FIGRParameterExplorerUIFigure);
            app.vThresholdSliderLabel.Text = 'vThreshold';
            app.vThresholdSlider = uislider(app.FIGRParameterExplorerUIFigure);
            app.vThresholdSlider.Limits = [0 0.02];
            app.vThresholdSlider.MajorTicks = [0 0.001111 0.002222 0.003333 0.004444 0.005555 0.006666 0.007777 0.008888 0.009999 0.01111 0.012221 0.013332 0.014443 0.015554 0.016665 0.017776 0.018887 0.02];
            app.vThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @vThresholdSliderValueChanged, true);
            app.vThresholdSlider.Value = 0.01;
            app.LoadexpressiondataMDAButton = uibutton(app.FIGRParameterExplorerUIFigure, 'push');
            app.LoadexpressiondataMDAButton.ButtonPushedFcn = createCallbackFcn(app, @LoadexpressiondataMDAButtonPushed, true);
            app.LoadexpressiondataMDAButton.Text = 'Load expression data (MDA)';
            app.SaveexpressiondataButton = uibutton(app.FIGRParameterExplorerUIFigure, 'push');
            app.SaveexpressiondataButton.Enable = 'off';
            app.SaveexpressiondataButton.Text = 'Save expression data';
            app.LoadtimepointsMDAButton = uibutton(app.FIGRParameterExplorerUIFigure, 'push');
            app.LoadtimepointsMDAButton.ButtonPushedFcn = createCallbackFcn(app, @LoadtimepointsMDAButtonPushed, true);
            app.LoadtimepointsMDAButton.Text = 'Load timepoints (MDA)';
            app.InferGRNButton = uibutton(app.FIGRParameterExplorerUIFigure, 'push');
            app.InferGRNButton.ButtonPushedFcn = createCallbackFcn(app, @InferGRNButtonPushed, true);
            app.InferGRNButton.Enable = 'off';
            app.InferGRNButton.Text = 'Infer GRN';
            % Show the figure after all components are created
            app.FIGRParameterExplorerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = figrApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FIGRParameterExplorerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FIGRParameterExplorerUIFigure)
        end
    end
end